classdef ex2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        EditField_P3       matlab.ui.control.EditField
        Label_ptotal       matlab.ui.control.Label
        Label_pg2          matlab.ui.control.Label
        Label_pg1          matlab.ui.control.Label
        Label_vf           matlab.ui.control.Label
        EscolhaButton      matlab.ui.control.Button
        PtotalLabel        matlab.ui.control.Label
        Pg2Label           matlab.ui.control.Label
        Pg1Label           matlab.ui.control.Label
        ValorfitnessLabel  matlab.ui.control.Label
        ResultadosLabel    matlab.ui.control.Label
        Label              matlab.ui.control.Label
        EditField_P2g2     matlab.ui.control.EditField
        EditField_Pg2      matlab.ui.control.EditField
        EditField_P2g1     matlab.ui.control.EditField
        EditField_Pg1      matlab.ui.control.EditField
        GeradoresLabel     matlab.ui.control.Label
        Image2             matlab.ui.control.Image
        FunoFitnessLabel   matlab.ui.control.Label
        RUNButton          matlab.ui.control.Button
        Image              matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            %carrega as variaveis 
            pg1     = str2double(app.EditField_Pg1.Value);
            pg1_2   = str2double(app.EditField_P2g1.Value);
            pg2     = str2double(app.EditField_Pg2.Value);
            pg2_2   = str2double(app.EditField_P2g2.Value);
            p3      = str2double(app.EditField_P3.Value);
            
            %save cachefile pg1 pg1_2 pg2 pg2_2 p3;
            save('cachefile.mat', 'pg1','pg1_2','pg2','pg2_2','p3','-append'); 
            
            %chama a função gacusto
            gacusto();
            load cachefile x1 x2 x3 x4;
            
            % atualiza os resultados
            app.Label_pg1.Text     = x1;
            app.Label_pg2.Text     = x2;
            app.Label_vf.Text      = x3;
            app.Label_ptotal.Text  = x4;
        
        end

        % Button pushed function: EscolhaButton
        function EscolhaButtonPushed(app, event)
            escolha
            close(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'img_2.png';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.FunoFitnessLabel = uilabel(app.UIFigure);
            app.FunoFitnessLabel.Text = 'Função Fitness';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'img_func.png';
            app.GeradoresLabel = uilabel(app.UIFigure);
            app.GeradoresLabel.Text = 'Geradores';
            app.EditField_Pg1 = uieditfield(app.UIFigure, 'text');
            app.EditField_Pg1.Value = '0.3';
            app.EditField_P2g1 = uieditfield(app.UIFigure, 'text');
            app.EditField_P2g1.Value = '0.01';
            app.EditField_Pg2 = uieditfield(app.UIFigure, 'text');
            app.EditField_Pg2.Value = '0.2';
            app.EditField_P2g2 = uieditfield(app.UIFigure, 'text');
            app.EditField_P2g2.Value = '0.2';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ResultadosLabel = uilabel(app.UIFigure);
            app.ResultadosLabel.Text = 'Resultados';
            app.ValorfitnessLabel = uilabel(app.UIFigure);
            app.ValorfitnessLabel.Text = 'Valor fitness:';
            app.Pg1Label = uilabel(app.UIFigure);
            app.Pg1Label.Text = 'Pg1:';
            app.Pg2Label = uilabel(app.UIFigure);
            app.Pg2Label.Text = 'Pg2:';
            app.PtotalLabel = uilabel(app.UIFigure);
            app.PtotalLabel.Text = 'Ptotal:';
            app.EscolhaButton = uibutton(app.UIFigure, 'push');
            app.EscolhaButton.ButtonPushedFcn = createCallbackFcn(app, @EscolhaButtonPushed, true);
            app.EscolhaButton.Text = 'Escolha';
            app.Label_vf = uilabel(app.UIFigure);
            app.Label_vf.Text = '';
            app.Label_pg1 = uilabel(app.UIFigure);
            app.Label_pg1.Text = '';
            app.Label_pg2 = uilabel(app.UIFigure);
            app.Label_pg2.Text = '';
            app.Label_ptotal = uilabel(app.UIFigure);
            app.Label_ptotal.Text = '';
            app.EditField_P3 = uieditfield(app.UIFigure, 'text');
            app.EditField_P3.Value = '0.5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ex2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end